classdef DataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        Button_openIMUData     matlab.ui.control.Button
        EditField_pathIMUData  matlab.ui.control.EditField
        Label_2                matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        Panel_2                matlab.ui.container.Panel
        GyroCheckBox           matlab.ui.control.CheckBox
        AccelCheckBox          matlab.ui.control.CheckBox
        MagneticCheckBox       matlab.ui.control.CheckBox
        Panel_3                matlab.ui.container.Panel
        Button_FFt             matlab.ui.control.Button
        Button_Original        matlab.ui.control.Button
        Button_Allon           matlab.ui.control.Button
    end

    
    properties (Access = public)
        IMUData                 double % Description
        Fs                      double
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Fs = 200;
        end

        % Button pushed function: Button_openIMUData
        function Button_openIMUDataPushed(app, event)
            [IMU_FileName,IMU_FilePath] = uigetfile('*.mat');
            if isequal(IMU_FileName,0)
               disp('>-----------警告：未选中IMU数据！！！');
            else
               disp(['>---选中数据为   ', fullfile(IMU_FilePath,IMU_FileName)]);               
               load(fullfile(IMU_FilePath,IMU_FileName));
               if exist('IMU') ~= 1
                   disp('>-----------警告：IMU数据为空！');
               else
                   app.IMUData = IMU;
                   app.EditField_pathIMUData.Value = fullfile(IMU_FilePath,IMU_FileName);
               end
            end               
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Fs = value;
        end

        % Button pushed function: Button_FFt
        function Button_FFtPushed(app, event)
            % 对3个轴的陀螺进行数据分析
            value = app.GyroCheckBox.Value;
            if value == 1
                if isempty(app.IMUData)
                    disp('>------警告！！IMU数据为空！');
                    return;
                end
                disp('>------对 陀螺 数据进行 频谱分析！');  
                fs = app.Fs;   % 采样频率
                L = length(app.IMUData);               
                
                figure;                
                %---------------------- X 轴陀螺
                subplot(1,3,1);
                y = app.IMUData(:,5);
                
                Y = fft(y);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = fs*(0:(L/2))/L;         

                plot(f,P1); 
                title('频谱 XGyro');
                xlabel('f (Hz)');
                ylabel('|P1(f)|');
                %---------------------- y 轴陀螺
                subplot(1,3,2);
                y = app.IMUData(:,6);
                
                Y = fft(y);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = fs*(0:(L/2))/L;         

                plot(f,P1); 
                title('频谱 YGyro');
                xlabel('f (Hz)');
                ylabel('|P1(f)|');
                
                %---------------------- z 轴陀螺
                subplot(1,3,3);
                y = app.IMUData(:,7);
                
                Y = fft(y);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = fs*(0:(L/2))/L;         

                plot(f,P1); 
                title('频谱 ZGyro');
                xlabel('f (Hz)');
                ylabel('|P1(f)|');                
            end
        end

        % Button pushed function: Button_Original
        function Button_OriginalPushed(app, event)
            % 对3个轴的陀螺进行原始数据绘制
            value = app.GyroCheckBox.Value;
            if value == 1
                if isempty(app.IMUData)
                    disp('>------警告！！IMU数据为空！');
                    return;
                end
                fs = app.Fs;   % 采样频率
                L = length(app.IMUData);   
                t = (0:L-1).*(1/fs);
                figure;
                subplot(1,3,1);
                plot(t,app.IMUData(:,5));
                xlabel('time(s)');
                ylabel('rad/s');
                title('Gyro X');       
                subplot(1,3,2);
                plot(t,app.IMUData(:,6));
                xlabel('time(s)');
                ylabel('rad/s');
                title('Gyro Y'); 
                subplot(1,3,3);
                plot(t,app.IMUData(:,7));
                xlabel('time(s)');
                ylabel('rad/s');
                title('Gyro Z');                 
            end
            
            % 对3个轴的 加计 进行原始数据绘制
            value = app.AccelCheckBox.Value;
            if value == 1
                if isempty(app.IMUData)
                    disp('>------警告！！IMU数据为空！');
                    return;
                end
                fs = app.Fs;   % 采样频率
                L = length(app.IMUData);   
                t = (0:L-1).*(1/fs);
                figure;
                subplot(1,3,1);
                plot(t,app.IMUData(:,2));
                xlabel('time(s)');
                ylabel('g');
                title('Acc X');       
                subplot(1,3,2);
                plot(t,app.IMUData(:,3));
                xlabel('time(s)');
                ylabel('g');
                title('Acc Y'); 
                subplot(1,3,3);
                plot(t,app.IMUData(:,4));
                xlabel('time(s)');
                ylabel('g');
                title('Acc Z');                 
            end                        
        end

        % Button pushed function: Button_Allon
        function Button_AllonPushed(app, event)
            % 对3个轴的陀螺进行 PSD 分析
            value = app.GyroCheckBox.Value;
            if value == 1           
                if isempty(app.IMUData)
                    disp('>------警告！！IMU数据为空！');
                    return;
                end
                disp('>-------------------------------------------<');
                disp('>------对 陀螺 数据进行 Allan方差分析！');  
                disp('>------注意：输入的陀螺数据单位为 rad/s ');  
                fs = app.Fs;   % 采样频率
                L = length(app.IMUData);   
                t0 = 1/fs;
                maxNumM = 100;
                maxM = 2.^floor(log2(L/2));
                m = logspace(log10(1), log10(maxM), maxNumM).';
                m = ceil(m); % m must be an integer.
                m = unique(m); % Remove duplicates.
                tau = m*t0;
                
                %---------------陀螺的Allan方差分析
                for j = 1:3
                    tmpGyro = app.IMUData(:,4+j);
                    [avarFromFunc, tauFromFunc] = allanvar(tmpGyro, m, fs);
                    adevFromFunc = sqrt(avarFromFunc);
                
                    % ARW 角度随机游走 N 噪声积分 斜率 -1/2
                    slope = -0.5;
                    logtau = log10(tau);
                    logadev = log10(adevFromFunc);
                    dlogadev = diff(logadev) ./ diff(logtau);
                    [~, i] = min(abs(dlogadev - slope));            
                    b = logadev(i) - slope*logtau(i);
                    logN = slope*log(1) + b;
                    N = 10^logN;                    
                    tauN = 1;
                    lineN = N ./ sqrt(tau);      
                    
                    % 零偏不稳定性 B  斜率 0
                    slope = 0;
                    logtau = log10(tau);
                    logadev = log10(adevFromFunc);
                    dlogadev = diff(logadev) ./ diff(logtau);
                    [~, i] = min(abs(dlogadev - slope));                    
                    % Find the y-intercept of the line.
                    b = logadev(i) - slope*logtau(i);                    
                    % Determine the bias instability coefficient from the line.
                    scfB = sqrt(2*log(2)/pi);
                    logB = b - log10(scfB);
                    B = 10^logB; 
                    tauB = tau(i);
                    lineB = B * scfB * ones(size(tau));
                    
                    % RRW 角速率随机游走 K 斜率1/2
                    slope = 0.5;
                    logtau = log10(tau);
                    logadev = log10(adevFromFunc);
                    dlogadev = diff(logadev) ./ diff(logtau);
                    [~, i] = min(abs(dlogadev - slope));                    
                    % Find the y-intercept of the line.
                    b = logadev(i) - slope*logtau(i);                    
                    % Determine the rate random walk coefficient from the line.
                    logK = slope*log10(3) + b;
                    K = 10^logK;
                    tauK = 3;
                    lineK = K .* sqrt(tau/3);

                    % 绘图显示
                    tauParams = [tauN, tauK, tauB];
                    params = [N, K, scfB*B];
                    figure
                    loglog(tauFromFunc, adevFromFunc, tauFromFunc, [lineN, lineK, lineB], '--', ...
                        tauParams, params, 'o',tauFromFunc,lineN+lineK+lineB,'r-.')                    
                    xlabel('\tau')
                    ylabel('\sigma(\tau)')
                    text(tauParams, params, {'N', 'K', '0.664B'})
                    grid on                    
                    
                    switch j
                        case 1
                            title('Allan xGyro');
                            disp(['>-----xGyro ARW N is     ',num2str(N),' r/sqrts    ',num2str(N*180/pi*60),' 度/sqrtH']);
                            disp(['>-----xGyro BiaI   B is     ',num2str(B),' r/s          ',num2str(B*180/pi*3600),' 度/H']);
                            disp(['>-----xGyro RRW K is     ',num2str(K),' r/s/sqrts  ',num2str(K*180/pi*3600^1.5),' 度/H/sqrtH']);
                            disp(['>-----xGyro Allan高斯  ','N*sqrt(fs/2):     ',num2str((N*sqrt(fs/2))*(180/pi*3600)),' 度/H;']);
                            disp(['>-----xGyro Allan漂移  ','B+K/sqrt(fs/2): ',num2str((B+K/sqrt(fs/2))*(180/pi*3600)),' 度/H']);
                            disp(['>-----xGyro Allan均方差  ',num2str((N*10+K/10+B)*(180/pi*3600)),' 度/H']);
                            disp(['>-----xGyro 数据均方差   ',num2str(sqrt(var(tmpGyro))*(180/pi*3600)),' 度/H']);  
                            disp('>-------------------------------------------<');
                        case 2
                            title('Allan yGyro');
                            disp(['>-----yGyro ARW N is     ',num2str(N),' r/sqrts    ',num2str(N*180/pi*60),' 度/sqrtH']);
                            disp(['>-----yGyro BiaI   B is     ',num2str(B),' r/s          ',num2str(B*180/pi*3600),' 度/H']);
                            disp(['>-----yGyro RRW K is     ',num2str(K),' r/s/sqrts  ',num2str(K*180/pi*3600^1.5),' 度/H/sqrtH']);
                            disp(['>-----yGyro Allan高斯  ','N*sqrt(fs/2):     ',num2str((N*sqrt(fs/2))*(180/pi*3600)),' 度/H;']);
                            disp(['>-----yGyro Allan漂移  ','B+K/sqrt(fs/2): ',num2str((B+K/sqrt(fs/2))*(180/pi*3600)),' 度/H']);
                            disp(['>-----yGyro Allan均方差  ',num2str((N*10+K/10+B)*(180/pi*3600)),' 度/H']);
                            disp(['>-----yGyro 数据均方差   ',num2str(sqrt(var(tmpGyro))*(180/pi*3600)),' 度/H']);  
                            disp('>-------------------------------------------<');
                        case 3
                            title('Allan zGyro');
                            disp(['>-----zGyro ARW N is     ',num2str(N),' r/sqrts    ',num2str(N*180/pi*60),' 度/sqrtH']);
                            disp(['>-----zGyro BiaI   B is     ',num2str(B),' r/s          ',num2str(B*180/pi*3600),' 度/H']);
                            disp(['>-----zGyro RRW K is     ',num2str(K),' r/s/sqrts  ',num2str(K*180/pi*3600^1.5),' 度/H/sqrtH']);
                            disp(['>-----zGyro Allan高斯  ','N*sqrt(fs/2):     ',num2str((N*sqrt(fs/2))*(180/pi*3600)),' 度/H;']);
                            disp(['>-----zGyro Allan漂移  ','B+K/sqrt(fs/2): ',num2str((B+K/sqrt(fs/2))*(180/pi*3600)),' 度/H']);
                            disp(['>-----zGyro Allan均方差  ',num2str((N*10+K/10+B)*(180/pi*3600)),' 度/H']);
                            disp(['>-----zGyro 数据均方差   ',num2str(sqrt(var(tmpGyro))*(180/pi*3600)),' 度/H']);  
                            disp('>-------------------------------------------<');
                    end                   
                    
                end

                    
%                     gyro = gyroparams('NoiseDensity', N, 'RandomWalk', K,'BiasInstability', B);
%                     omegaSim = helperAllanVarianceExample(L, fs, gyro);
%                     [avarSim, tauSim] = allanvar(omegaSim, m, fs);
%                     adevSim = sqrt(avarSim);
%                     adevSim = mean(adevSim, 2); % Use the mean of the simulations.
%                     figure
%                     loglog(tauFromFunc, adevFromFunc, tauSim, adevSim, '--')
%                     title('Allan Deviation of HW and Simulation')
%                     xlabel('\tau');
%                     ylabel('\sigma(\tau)')
%                     legend('HW', 'SIM')
%                     grid on
%                     
%                     
%                     figure;
%                     plot(xGyro); hold on; plot(omegaSim(:,1),'r');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '惯性器件采集数据分析';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '1.选择数据';
            app.Button_openIMUData = uibutton(app.Panel, 'push');
            app.Button_openIMUData.ButtonPushedFcn = createCallbackFcn(app, @Button_openIMUDataPushed, true);
            app.Button_openIMUData.Text = '读取数据';
            app.EditField_pathIMUData = uieditfield(app.Panel, 'text');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '采样频率';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 200;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '2.待分析器件选择';
            app.GyroCheckBox = uicheckbox(app.Panel_2);
            app.GyroCheckBox.Text = 'Gyro';
            app.AccelCheckBox = uicheckbox(app.Panel_2);
            app.AccelCheckBox.Text = 'Accel';
            app.MagneticCheckBox = uicheckbox(app.Panel_2);
            app.MagneticCheckBox.Text = 'Magnetic';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '3.信号分析';
            app.Button_FFt = uibutton(app.Panel_3, 'push');
            app.Button_FFt.ButtonPushedFcn = createCallbackFcn(app, @Button_FFtPushed, true);
            app.Button_FFt.Text = '频谱分析 FFt';
            app.Button_Original = uibutton(app.Panel_3, 'push');
            app.Button_Original.ButtonPushedFcn = createCallbackFcn(app, @Button_OriginalPushed, true);
            app.Button_Original.Text = '原始数据';
            app.Button_Allon = uibutton(app.Panel_3, 'push');
            app.Button_Allon.ButtonPushedFcn = createCallbackFcn(app, @Button_AllonPushed, true);
            app.Button_Allon.Text = 'Allon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end